classdef NEBRIJA_STRUCTURAL_ANALYSIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        RootGrid        matlab.ui.container.GridLayout
        Heading         matlab.ui.control.Label
        WorkspaceGrid   matlab.ui.container.GridLayout
        InputTabs       matlab.ui.container.TabGroup
        SupportTab      matlab.ui.container.Tab
        SupportGrid     matlab.ui.container.GridLayout
        SupTable        matlab.ui.control.Table
        SupportButtons  matlab.ui.container.GridLayout
        AddSupButton    matlab.ui.control.Button
        DelSupButton    matlab.ui.control.Button
        LoadTab         matlab.ui.container.Tab
        LoadGrid        matlab.ui.container.GridLayout
        LoadTable       matlab.ui.control.Table
        LoadButtons     matlab.ui.container.GridLayout
        AddLoadButton   matlab.ui.control.Button
        EditLoadButton  matlab.ui.control.Button
        DelLoadButton   matlab.ui.control.Button
        OptionsTab      matlab.ui.container.Tab
        OptionsGrid     matlab.ui.container.GridLayout
        SymEICheck      matlab.ui.control.CheckBox
        PerSpanCheck    matlab.ui.control.CheckBox
        StepGrid        matlab.ui.container.GridLayout
        StepLab         matlab.ui.control.Label
        StepField       matlab.ui.control.EditField
        FmDeflDrop      matlab.ui.control.DropDown
        TargetGrid      matlab.ui.container.GridLayout
        TgtLab          matlab.ui.control.Label
        FmTargetField   matlab.ui.control.NumericEditField
        IButton         matlab.ui.control.Button
        SpanLabel       matlab.ui.control.Label
        AnswersTab      matlab.ui.container.Tab
        FinalGrid       matlab.ui.container.GridLayout
        FinalLabel      matlab.ui.control.Label
        ReactTable      matlab.ui.control.Table
        SummaryArea     matlab.ui.control.TextArea
        FBDTab          matlab.ui.container.Tab
        FBDGrid         matlab.ui.container.GridLayout
        axBeam          matlab.ui.control.UIAxes
        ChartsTab       matlab.ui.container.Tab
        ChartGrid       matlab.ui.container.GridLayout
        axV             matlab.ui.control.UIAxes
        axM             matlab.ui.control.UIAxes
        axD             matlab.ui.control.UIAxes
        SettingsGrid    matlab.ui.container.GridLayout
        UnitsLabel      matlab.ui.control.Label
        UnitsDD         matlab.ui.control.DropDown
        MethodLabel     matlab.ui.control.Label
        MethodDropDown  matlab.ui.control.DropDown
        TitleLabel      matlab.ui.control.Label
        TitleField      matlab.ui.control.EditField
        LenLab          matlab.ui.control.Label
        LengthField     matlab.ui.control.NumericEditField
        HingeLab        matlab.ui.control.Label
        HingeField      matlab.ui.control.EditField
        ELab            matlab.ui.control.Label
        EField          matlab.ui.control.EditField
        ILab            matlab.ui.control.Label
        IField          matlab.ui.control.EditField
        EIButton        matlab.ui.control.Button
        UnitsNote       matlab.ui.control.Label
        CreatorNote     matlab.ui.control.Label
        FooterGrid      matlab.ui.container.GridLayout
        StatusLabel     matlab.ui.control.Label
        CalcButton      matlab.ui.control.Button
        StopButton      matlab.ui.control.Button
        ClearButton     matlab.ui.control.Button
    end

    properties (Access = private)
        selSup = []
        selLoad = []
        Loads = struct('Type',{},'A',{},'B',{},'V1',{},'V2',{},'Dir',{})
        Busy = false
        StopRequested = false
        SolutionText = ''
    end
    methods (Access = private)
        function applyUnits(app)
            % the one place that says out loud what each field is measured in
            if isempty(app.UnitsDD) || ~isvalid(app.UnitsDD), return; end
            u = alesingCoveInternal.units();
            if isDefaultBeam(app, ~u.eng)
                app.LengthField.Value = u.Ldef;
                app.EField.Value = u.Etxt;
                app.IField.Value = u.Itxt;
                x = u.supdef;
                app.SupTable.Data = {x(1),'Pinned',[],[]; x(2),'Roller',[],[]; x(3),'Roller',[],[]};
                if numel(app.Loads) == 1 && strcmp(app.Loads(1).Type, 'UDL') && ...
                        isequal([app.Loads(1).V1 app.Loads(1).V2], [0.7*0 + u.demoW, u.demoW])
                    app.Loads = struct('Type','UDL', 'A',0, 'B',u.demoB, 'V1',u.demoW, ...
                        'V2',u.demoW, 'Dir','Downward');
                end
            end
            app.LenLab.Text = ['Length L (' u.L ')'];
            app.ELab.Text = ['E (' u.E ')'];
            app.ILab.Text = ['I (' u.I4 ')'];
            app.HingeLab.Text = ['Hinges x (' u.L ')'];
            app.StepLab.Text = ['Steps x (' u.L ')'];
            app.TgtLab.Text = ['Check v at x (' u.L ')'];
            app.UnitsNote.Text = u.note;
            app.SupTable.ColumnName = {['x (' u.L ')'], 'Type', ['k (' u.w ')'], ...
                ['Settlement (' u.d ')']};
            app.EField.Tooltip = ['One value for all spans, or one per span (' u.Ehint ...
                '), or a multiple of E such as 2E or E/2'];
            app.IField.Tooltip = ['One value per span (' u.Ihint '), or a multiple of I ' ...
                'such as 2I, I/2, 3I/4 (or use the Set I button)'];
            refreshLoadTable(app);
        end

        function tf = isDefaultBeam(app, other)
            % true while the form still holds the beam the app opens with, in the system
            % that is being left
            u = alesingCoveInternal.unitsDef(other);
            tf = strcmp(app.EField.Value, u.Etxt) && strcmp(app.IField.Value, u.Itxt) && ...
                isequal(app.LengthField.Value, u.Ldef) && ...
                isempty(strtrim(app.HingeField.Value)) && isempty(strtrim(app.StepField.Value)) && ...
                ~app.PerSpanCheck.Value && ~app.SymEICheck.Value;
            if ~tf, return; end
            D = app.SupTable.Data;
            if size(D, 1) ~= 3, tf = false; return; end
            x = [alesingCoveInternal.cellNum(D{1,1}), alesingCoveInternal.cellNum(D{2,1}), alesingCoveInternal.cellNum(D{3,1})];
            tf = max(abs(x - u.supdef)) < 1e-9 && numel(app.Loads) == 1 && ...
                strcmp(app.Loads(1).Type, 'UDL') && ...
                isequal([app.Loads(1).A app.Loads(1).B app.Loads(1).V1 app.Loads(1).V2], ...
                        [0, u.demoB, u.demoW, u.demoW]);
        end

        function syncFmOptions(app)
            % which of the extra boxes mean something with the method in use
            isFM = strcmp(app.MethodDropDown.Value, 'Force Method');
            if isFM
                app.FmDeflDrop.Enable = 'on';
                app.FmTargetField.Enable = 'on';
            else
                app.FmDeflDrop.Enable = 'off';
                app.FmTargetField.Enable = 'off';
            end
            if app.PerSpanCheck.Value
                app.StepField.Enable = 'on';
            else
                app.StepField.Enable = 'off';
            end
        end

        function setBusy(app, tf)
            if ~isvalid(app) || ~isvalid(app.UIFigure), return; end
            app.Busy = tf;
            oo = {'on','off'};
            app.CalcButton.Enable  = oo{tf+1};
            app.ClearButton.Enable = oo{tf+1};
            app.StopButton.Enable  = oo{(~tf)+1};
        end

        function sortSupports(app)
            S = app.SupTable.Data;
            if size(S,1) >= 2
                x = cellfun(@alesingCoveInternal.cellNum, S(:,1));
                [~, ord] = sort(x);               % empty positions go last
                app.SupTable.Data = S(ord,:);
            end
            app.selSup = [];
            refreshSupLabels(app);
        end

        function refreshSupLabels(app)
            n = size(app.SupTable.Data, 1);
            if n == 0
                app.SupTable.RowName = {};
            else
                nm = cell(1,n);
                for i = 1:n, nm{i} = alesingCoveInternal.supLabel(i); end
                app.SupTable.RowName = nm;
            end
        end

        function refreshLoadTable(app)
            n = numel(app.Loads);
            D = cell(n,3);
            for r = 1:n, D(r,:) = alesingCoveInternal.loadRow(app.Loads(r)); end
            app.LoadTable.Data = alesingCoveInternal.engLbl(D);
        end

        function updateSpans(app)
            try
                S = app.SupTable.Data;  x = [];
                for i = 1:size(S,1)
                    v = alesingCoveInternal.cellNum(S{i,1});
                    if ~isnan(v), x(end+1) = v; end %#ok<AGROW>
                end
                sx = unique(round([0, app.LengthField.Value, x]*1e9)/1e9);
                p = cell(1, numel(sx)-1);
                for k = 1:numel(sx)-1, p{k} = sprintf('%g-%g', sx(k), sx(k+1)); end
                txt = sprintf(alesingCoveInternal.engLbl('Spans: %d  (%s m)'), numel(p), strjoin(p, ', '));
                if app.PerSpanCheck.Value && ~isempty(strtrim(app.StepField.Value))
                    sg = alesingCoveInternal.getSpanBreakpoints(app.LengthField.Value, x, app.StepField.Value, true);
                    if numel(sg) - 1 ~= numel(p)
                        q = cell(1, numel(sg)-1);
                        for k = 1:numel(sg)-1, q{k} = sprintf('%g-%g', sg(k), sg(k+1)); end
                        txt = sprintf(alesingCoveInternal.engLbl('Parts: %d  (%s m)'), numel(q), strjoin(q, ', '));
                    end
                end
                if app.SymEICheck.Value, txt = [txt '   -  E and I as multiples only']; end
                app.SpanLabel.Text = alesingCoveInternal.engLbl(txt);
            catch
                app.SpanLabel.Text = '';
            end
        end

        function showLoadDialog(app, row)
            c = alesingCoveInternal.beamColors();  fn = alesingCoveInternal.pickFont();
            if isempty(row)
                ld = struct('Type','Point', 'A',[], 'B',[], 'V1',[], 'V2',[], 'Dir','Downward');
                ttl = 'Add load';
            else
                ld = app.Loads(row);
                ttl = 'Edit load';
            end
            p = app.UIFigure.Position;  w = 350;  h = 310;
            dlg = uifigure('Name', ttl, 'Color', c.bg, 'Resize', 'off', 'WindowStyle', 'modal', ...
                'Position', [p(1)+round((p(3)-w)/2), p(2)+round((p(4)-h)/2), w, h]);
            try
                dlg.Theme = 'light';
            catch
            end

            g = uigridlayout(dlg, [5 1]);  g.BackgroundColor = c.bg;  g.Padding = [14 12 14 12];
            g.RowHeight = {28, '1x', 34, 34, 34};  g.RowSpacing = 6;

            tg = uigridlayout(g, [1 2]);  tg.Padding = [0 0 0 0];  tg.ColumnWidth = {75,'1x'};
            tg.BackgroundColor = c.bg;
            uilabel(tg, 'Text','Load type', 'FontWeight','bold', 'FontColor',c.text, 'FontName',fn);
            dd = uidropdown(tg, 'Items', {'Point load','Point moment','Horizontal load', ...
                    'Uniformly distributed load (UDL)','Uniformly varying load (UVL)'}, ...
                'ItemsData', {'Point','Moment','Horizontal','UDL','UVL'}, 'Value', ld.Type, ...
                'BackgroundColor', c.surface, 'FontColor', c.text, 'FontName', fn);
            dd.ValueChangedFcn = createCallbackFcn(app, @LoadDlgTypeChanged, true);

            fp = uigridlayout(g, [1 2]);  fp.Padding = [0 0 0 0];  fp.ColumnWidth = {'1.6x','1x'};
            fp.BackgroundColor = c.bg;  fp.RowSpacing = 6;

            hint = uilabel(g, 'Text','', 'WordWrap','on', 'FontColor',c.muted, 'FontSize',10, ...
                'VerticalAlignment','top', 'FontName',fn);
            err  = uilabel(g, 'Text','', 'WordWrap','on', 'FontColor',c.danger, 'FontSize',10, ...
                'VerticalAlignment','top', 'FontName',fn);

            bg = uigridlayout(g, [1 2]);  bg.Padding = [0 0 0 0];  bg.BackgroundColor = c.bg;
            ok = uibutton(bg, 'Text','OK', 'FontWeight','bold', 'FontName',fn, ...
                'BackgroundColor',c.violet, 'FontColor',[1 1 1]);
            ok.ButtonPushedFcn = createCallbackFcn(app, @LoadDlgOK, true);
            ca = uibutton(bg, 'Text','Cancel', 'FontName',fn, 'BackgroundColor',c.btn, 'FontColor',c.text);
            ca.ButtonPushedFcn = createCallbackFcn(app, @LoadDlgCancel, true);

            dlg.UserData = struct('row',row, 'ld',ld, 'dd',dd, 'fp',fp, 'hint',hint, 'err',err, 'f',struct());
            buildLoadFields(app, dlg);
        end

        function buildLoadFields(~, dlg)
            c = alesingCoveInternal.beamColors();  fn = alesingCoveInternal.pickFont();
            U = dlg.UserData;  ty = U.dd.Value;  ld = U.ld;
            delete(U.fp.Children);
            switch ty
                case 'Point'
                    lab = {'Position x (m)','Load P (kN)'};  key = {'A','V1'};
                    dirs = {'Downward','Upward'};
                    hint = 'Enter the size of the load, then choose which way it acts.';
                case 'Moment'
                    lab = {'Position x (m)','Moment M (kN·m)'};  key = {'A','V1'};
                    dirs = {'Clockwise','Counterclockwise'};
                    hint = 'Enter the size of the moment, then choose its rotation.';
                case 'Horizontal'
                    lab = {'Position x (m)','Load H (kN)'};  key = {'A','V1'};
                    dirs = {'To the right','To the left'};
                    hint = 'Axial load acting along the beam.';
                case 'UDL'
                    lab = {'Start x (m)','End x (m)','Intensity w (kN/m)'};  key = {'A','B','V1'};
                    dirs = {'Downward','Upward'};
                    hint = 'Constant intensity between the start and end points.';
                otherwise   % UVL
                    lab = {'Start x (m)','End x (m)','Intensity at start w₁ (kN/m)','Intensity at end w₂ (kN/m)'};
                    key = {'A','B','V1','V2'};
                    dirs = {'Downward','Upward'};
                    hint = 'Triangular: enter 0 for w₁ or w₂.  Trapezoidal: enter both values.';
            end
            n = numel(lab) + 1;
            U.fp.RowHeight = repmat({28}, 1, n);
            f = struct();
            for k = 1:numel(lab)
                uilabel(U.fp, 'Text', alesingCoveInternal.engLbl(lab{k}), 'FontName',fn, 'FontColor',c.text);
                val = '';
                if strcmp(ld.Type, ty) && ~isempty(ld.(key{k})), val = sprintf('%g', ld.(key{k})); end
                f.(key{k}) = uieditfield(U.fp, 'text', 'Value',val, 'BackgroundColor',c.surface, ...
                    'FontColor',c.text, 'FontName',fn);
            end
            uilabel(U.fp, 'Text','Direction', 'FontName',fn, 'FontColor',c.text);
            dv = dirs{1};
            if strcmp(ld.Type, ty) && any(strcmp(ld.Dir, dirs)), dv = ld.Dir; end
            f.Dir = uidropdown(U.fp, 'Items',dirs, 'Value',dv, 'BackgroundColor',c.surface, ...
                'FontColor',c.text, 'FontName',fn);
            U.f = f;
            U.hint.Text = hint;
            dlg.UserData = U;
        end

        function B = readBeam(app)
            u = alesingCoveInternal.units();                                  % what the typed numbers mean
            L = app.LengthField.Value;
            if isempty(L) || L <= 0, error('Enter a beam length greater than zero.'); end

            % ---- supports: [x type settlement(m) k]
            S = app.SupTable.Data;  n = size(S,1);
            if n < 1, error('Add at least one support.'); end
            tn = {'Fixed','Pinned','Roller','Spring'};
            sup = zeros(n,4);
            for i = 1:n
                x = alesingCoveInternal.cellNum(S{i,1});
                if isnan(x), error('Support %s: enter the position.', alesingCoveInternal.supLabel(i)); end
                if x < 0 || x > L
                    error('Support %s is outside the beam (0 to %g %s).', alesingCoveInternal.supLabel(i), L, u.L);
                end
                t = find(strcmpi(S{i,2}, tn), 1);
                if isempty(t), error('Support %s: choose a type.', alesingCoveInternal.supLabel(i)); end
                k = alesingCoveInternal.cellNum(S{i,3});  d = alesingCoveInternal.cellNum(S{i,4});
                if isnan(d), d = 0; end
                if t == 4
                    if isnan(k) || k <= 0
                        error('Support %s: enter the spring stiffness k (%s).', alesingCoveInternal.supLabel(i), u.w);
                    end
                    d = 0;
                else
                    k = 0;
                end
                sup(i,:) = [x, t, d/u.sc, k];            % the settlement comes in as mm or in
            end
            sup = sortrows(sup, 1);
            if any(diff(sup(:,1)) < 1e-9), error('Two supports are at the same position.'); end

            % ---- spans, section segments, E and I (absolute, relative or symbolic)
            sx  = unique(round([0, L, sup(:,1)']*1e9)/1e9);
            nSp = numel(sx) - 1;
            vary = app.PerSpanCheck.Value;
            if vary
                bx = alesingCoveInternal.getSpanBreakpoints(L, sup(:,1)', app.StepField.Value, true);
            else
                bx = sx;
            end
            nSeg = numel(bx) - 1;
            symEI = app.SymEICheck.Value;
            [E, ~, s1] = alesingCoveInternal.parseFlexField(app.EField.Value, 'E', u.Ebase, symEI);
            [I, ~, s2] = alesingCoveInternal.parseFlexField(app.IField.Value, 'I', u.Ibase, symEI);
            symMode = symEI || s1 || s2;
            if any(~isfinite(E)) || any(~isfinite(I)) || any(E <= 0) || any(I <= 0)
                error('E and I must be positive numbers or multiples such as 2I or I/2.');
            end
            if symMode && (any(abs(sup(:,3)) > 1e-12) || any(sup(:,4) > 0))
                error(['Symbolic EI can be used only when there are no support settlements and no ' ...
                       'spring supports (those need a numerical EI).']);
            end
            ec = 1;  sc = 1;
            if symMode
                E = E/E(1);  I = I/I(1);        % only the multiples of E and of I matter
            else
                ec = u.ef;                      % psi -> kip/ft^2, nothing to do in metric
                sc = u.if4;                     % mm^4 -> m^4,  in^4 -> ft^4
            end
            if vary
                segE = alesingCoveInternal.flexLists(E, nSeg, nSp, bx, sx);
                segI = alesingCoveInternal.flexLists(I, nSeg, nSp, bx, sx);
                B_E  = alesingCoveInternal.flexLists(segE, nSp, nSeg, sx, bx);
                B_I  = alesingCoveInternal.flexLists(segI, nSp, nSeg, sx, bx);
            else
                B_E = alesingCoveInternal.flexLists(E, nSp, nSp, sx, sx);
                B_I = alesingCoveInternal.flexLists(I, nSp, nSp, sx, sx);
                segE = B_E;  segI = B_I;
            end

            % ---- hinges
            Hg = [];
            if strcmp(app.HingeField.Enable, 'on') && ~isempty(strtrim(app.HingeField.Value))
                Hg = alesingCoveInternal.parseList(app.HingeField.Value, 'Hinges');
                if any(Hg <= 0 | Hg >= L), error('Hinges must be inside the beam.'); end
            end

            % ---- loads (positive = downward, counterclockwise, to the right)
            P = zeros(0,2);  M = P;  H = P;  W = zeros(0,4);
            for r = 1:numel(app.Loads)
                ld = app.Loads(r);  s = alesingCoveInternal.loadSign(ld);
                if ld.A < 0 || ld.A > L || (~isempty(ld.B) && ld.B > L)
                    error('Load %d is outside the beam (0 to %g %s). Edit or delete it.', r, L, u.L);
                end
                switch ld.Type
                    case 'Point',      P(end+1,:) = [ld.A, s*ld.V1]; %#ok<AGROW>
                    case 'Moment',     M(end+1,:) = [ld.A, s*ld.V1]; %#ok<AGROW>
                    case 'Horizontal', H(end+1,:) = [ld.A, s*ld.V1]; %#ok<AGROW>
                    case 'UDL',        W(end+1,:) = [ld.A, ld.B, s*ld.V1, s*ld.V1]; %#ok<AGROW>
                    case 'UVL',        W(end+1,:) = [ld.A, ld.B, s*ld.V1, s*ld.V2]; %#ok<AGROW>
                end
            end

            name = strtrim(app.TitleField.Value);
            if isempty(name), name = 'Beam'; end
            B.name = name;  B.L = L;  B.spanX = sx;  B.sup = sup;
            B.sym = symMode;
            B.E = B_E*ec;  B.I = B_I*sc;        % per support span, in the working units
            B.segX = bx;                          % EI break points: supports and steps
            B.segE = segE*ec;  B.segI = segI*sc;    % one E and one I per section segment
            B = alesingCoveInternal.ensureBProps(B);
            B.fmDeflMethod = app.FmDeflDrop.Value;
            B.fmTargetX = app.FmTargetField.Value;
            if ~isfinite(B.fmTargetX) || B.fmTargetX < 0, B.fmTargetX = 0; end
            B.P = P;  B.H = H;  B.M = M;  B.hinge = reshape(Hg,1,[]);
            % Distributed loads: cut at every start/end point and at every support/hinge,
            % with overlaps added together -> non-overlapping pieces, none crossing a span
            % boundary. Every analysis (built-in or your own script) receives this clean list.
            B.W  = alesingCoveInternal.splitLoads(W, [sup(:,1); B.hinge(:); B.segX(:)]);
        end

        function showResults(app, B, R, method)
            tn = {'Fixed','Pinned','Roller','Spring'};
            ns = size(B.sup,1);
            D = cell(0,3);
            for s = 1:ns
                t = B.sup(s,2);  nm = alesingCoveInternal.supLabel(s);
                where = sprintf(alesingCoveInternal.engLbl('%s, x = %.2f m'), tn{t}, B.sup(s,1));
                ry = alesingCoveInternal.fz(R.Ry(s));
                if ry >= 0, arrow = char(8593); else, arrow = char(8595); end
                D(end+1,:) = {[nm 'y'], sprintf('%.2f kN %s', ry, arrow), where}; %#ok<AGROW>
                if t <= 2
                    D(end+1,:) = {[nm 'x'], sprintf('%.2f kN', alesingCoveInternal.fz(R.Rx(s))), where}; %#ok<AGROW>
                end
                if t == 1
                    D(end+1,:) = {['M' nm], sprintf('%.2f kN·m', alesingCoveInternal.fz(R.Mr(s))), where}; %#ok<AGROW>
                end
            end
            app.ReactTable.Data = alesingCoveInternal.engLbl(D);

            if R.DI < 0,      cls = 'Unstable';
            elseif R.DI == 0, cls = 'Statically determinate';
            else,             cls = sprintf('Statically indeterminate, degree %d', R.DI);
            end
            ln = { ...
                sprintf('Method: %s', method)
                sprintf('Rigidity: %s', B.fullDesc)
                alesingCoveInternal.deflFromLine(R)
                sprintf('Beam: %s  (L = %g m, %d span(s))', B.name, B.L, numel(B.E))
                sprintf('Degree of indeterminacy: %d  (%s)', R.DI, cls)
                ''
                'MAXIMUM VALUES'
                sprintf('Shear: %.2f kN at x = %.2f m', alesingCoveInternal.fz(R.Vmax), R.xV)
                sprintf('Moment: %.2f kN·m at x = %.2f m', alesingCoveInternal.fz(R.Mmax), R.xM)
                alesingCoveInternal.deflLine(B, R)};
            app.SummaryArea.Value = alesingCoveInternal.engLbl(ln);
        end

        function setStatus(app, msg, kind)
            c = alesingCoveInternal.beamColors();
            if islogical(kind), if kind, kind = 'err'; else, kind = 'info'; end, end
            switch kind
                case 'ok',   col = c.success;
                case 'warn', col = c.warning;
                case 'err',  col = c.danger;
                otherwise,   col = c.text;
            end
            app.StatusLabel.Text = alesingCoveInternal.engLbl(msg);
            app.StatusLabel.FontColor = col;
        end

        function sx = spanBreaks(app)
            % span boundaries: 0, every support inside the beam, and L.  With
            % "one E and I per span / step" ticked, the entered section steps are
            % boundaries as well, so E and I are then given per segment.
            L = app.LengthField.Value;
            S = app.SupTable.Data;  x = [];
            for i = 1:size(S,1)
                v = alesingCoveInternal.cellNum(S{i,1});
                if ~isnan(v) && v > 0 && v < L, x(end+1) = v; end %#ok<AGROW>
            end
            sx = unique(round([0, L, x]*1e9)/1e9);
            if app.PerSpanCheck.Value && ~isempty(strtrim(app.StepField.Value))
                sx = alesingCoveInternal.getSpanBreakpoints(L, x, app.StepField.Value, true);
            end
        end

        function showIDialog(app, varying)
            c = alesingCoveInternal.beamColors();  fn = alesingCoveInternal.pickFont();
            sx  = spanBreaks(app);
            nSp = numel(sx) - 1;

            % current values (reuse what is already typed in the I field)
            cur = [];
            try
                cur = alesingCoveInternal.parseList(app.IField.Value, 'I');
            catch
            end
            v0 = alesingCoveInternal.units().Ibase;              % what an empty I cell falls back to
            if ~isempty(cur), v0 = cur(1); end
            if isscalar(cur), cur = repmat(cur, 1, nSp); end
            if numel(cur) ~= nSp, cur = repmat(v0, 1, nSp); end

            if varying
                D = cell(nSp, 2);
                for k = 1:nSp
                    D{k,1} = sprintf('Span %d  (x = %g to %g m)', k, sx(k), sx(k+1));
                    D{k,2} = sprintf('%.10g', cur(k));
                end
                hint = sprintf(alesingCoveInternal.engLbl('Enter I of each of the %d span(s), in mm⁴.'), nSp);
                ttl  = 'Varying I - input per span';
            else
                D = {sprintf('All %d span(s)', nSp), sprintf('%.10g', v0)};
                hint = 'One I value (mm⁴) used for every span.';
                ttl  = 'Uniform I';
            end

            p = app.UIFigure.Position;  w = 430;  h = 170 + 26*min(size(D,1), 8);
            dlg = uifigure('Name', ttl, 'Color', c.bg, 'Resize', 'off', 'WindowStyle', 'modal', ...
                'Position', [p(1)+round((p(3)-w)/2), p(2)+round((p(4)-h)/2), w, h]);
            try
                dlg.Theme = 'light';
            catch
            end
            g = uigridlayout(dlg, [4 1]);  g.BackgroundColor = c.bg;  g.Padding = [14 12 14 12];
            g.RowHeight = {22, '1x', 30, 34};  g.RowSpacing = 6;
            uilabel(g, 'Text', alesingCoveInternal.engLbl(hint), 'FontColor', c.text, 'FontName', fn);
            tbl = uitable(g, 'Data', D, 'ColumnName', alesingCoveInternal.engLbl({'Span','I (mm⁴)'}), 'RowName', {}, ...
                'ColumnEditable', [false true], 'ColumnFormat', {'char','char'}, ...
                'ColumnWidth', {'auto', 140}, 'BackgroundColor', [c.surface; c.zebra], ...
                'ForegroundColor', c.text, 'FontName', fn);
            err = uilabel(g, 'Text', '', 'WordWrap', 'on', 'FontColor', c.danger, 'FontSize', 10, ...
                'VerticalAlignment', 'top', 'FontName', fn);
            bg = uigridlayout(g, [1 2]);  bg.Padding = [0 0 0 0];  bg.BackgroundColor = c.bg;
            ok = uibutton(bg, 'Text', 'OK', 'FontWeight', 'bold', 'FontName', fn, ...
                'BackgroundColor', c.violet, 'FontColor', [1 1 1]);
            ok.ButtonPushedFcn = createCallbackFcn(app, @IDlgOK, true);
            ca = uibutton(bg, 'Text', 'Cancel', 'FontName', fn, 'BackgroundColor', c.btn, 'FontColor', c.text);
            ca.ButtonPushedFcn = createCallbackFcn(app, @IDlgCancel, true);
            dlg.UserData = struct('varying', varying, 'tbl', tbl, 'err', err);
        end

        function sx = supBreaksOnly(app)
            % breakpoints of the SUPPORT spans (no section steps)
            L = app.LengthField.Value;
            rows = app.SupTable.Data;  xs = [];
            for r = 1:size(rows, 1)
                x = alesingCoveInternal.cellNum(rows{r, 1});
                if ~isnan(x) && isfinite(x), xs(end+1) = x; end %#ok<AGROW>
            end
            sx = unique(round([0, L, xs]*1e9)/1e9);
        end

        function showEIEditor(app)
            c = alesingCoveInternal.beamColors();  fn = alesingCoveInternal.pickFont();
            bx = spanBreaks(app);   nSeg = numel(bx) - 1;
            sx = supBreaksOnly(app);
            sy = app.SymEICheck.Value;
            eC = alesingCoveInternal.flexEntries(app.EField.Value, nSeg);
            iC = alesingCoveInternal.flexEntries(app.IField.Value, nSeg);
            D = cell(nSeg, 4);
            for k = 1:nSeg
                if nSeg > numel(sx) - 1
                    D{k,1} = sprintf('Part %d  (%g to %g m)', k, bx(k), bx(k+1));
                else
                    D{k,1} = sprintf('Span %d  (%g to %g m)', k, bx(k), bx(k+1));
                end
                D{k,2} = eC{k};
                D{k,3} = iC{k};
                D{k,4} = '';
            end
            D = alesingCoveInternal.eiPreview(D, sy);
            if sy
                hint = 'Multiples of E and of I (2E, I/2, 3I/4, ...). No numerical EI is used, so the slopes and the deflections come out in terms of EI.';
            else
                hint = 'E in kN/m² and I in mm⁴. Enter a number, or a multiple such as 2I or I/2 - the base value is the first number of that column.';
            end
            ttl = 'E and I per span / section';
            p = app.UIFigure.Position;  w = 580;  h = 216 + 24*min(nSeg, 10);
            dlg = uifigure('Name', ttl, 'Color', c.bg, 'Resize', 'off', 'WindowStyle', 'modal', ...
                'Position', [p(1)+round((p(3)-w)/2), p(2)+round((p(4)-h)/2), w, h]);
            try
                dlg.Theme = 'light';
            catch
            end
            g = uigridlayout(dlg, [4 1]);  g.BackgroundColor = c.bg;  g.Padding = [14 12 14 12];
            g.RowHeight = {40, '1x', 22, 34};  g.RowSpacing = 6;
            uilabel(g, 'Text', alesingCoveInternal.engLbl(hint), 'FontColor', c.text, 'WordWrap', 'on', 'FontName', fn);
            tbl = uitable(g, 'Data', D, 'ColumnName', alesingCoveInternal.engLbl({'Span / section', 'E (kN/m²)', 'I (mm⁴)', 'Effective EI'}), ...
                'RowName', {}, 'ColumnEditable', [false true true false], ...
                'ColumnFormat', {'char', 'char', 'char', 'char'}, ...
                'ColumnWidth', {'auto', 116, 116, 150}, ...
                'BackgroundColor', [c.surface; c.zebra], 'ForegroundColor', c.text, 'FontName', fn);
            tbl.UserData = struct('sym', sy);      % what the last column has to show
            tbl.CellEditCallback = @(s, ~) EIDlgEdit(app, s);
            err = uilabel(g, 'Text', '', 'WordWrap', 'on', 'FontColor', c.danger, 'FontSize', 10, ...
                'HorizontalAlignment', 'center', 'FontName', fn);
            bg = uigridlayout(g, [1 3]);  bg.Padding = [0 0 0 0];  bg.BackgroundColor = c.bg;
            bg.ColumnWidth = {'1x', '1x', '1x'};
            fi = uibutton(bg, 'Text', 'Fill with first', 'FontName', fn, 'BackgroundColor', c.btn, 'FontColor', c.text);
            fi.ButtonPushedFcn = @(~, ~) EIDlgFill(app, tbl);
            ok = uibutton(bg, 'Text', 'Apply', 'FontWeight', 'bold', 'FontName', fn, ...
                'BackgroundColor', c.violet, 'FontColor', [1 1 1]);
            ok.ButtonPushedFcn = @(~, ~) EIDlgApply(app, tbl, err, dlg);
            ca = uibutton(bg, 'Text', 'Cancel', 'FontName', fn, 'BackgroundColor', c.btn, 'FontColor', c.text);
            ca.ButtonPushedFcn = createCallbackFcn(app, @IDlgCancel, true);
        end

        function EIDlgEdit(app, tbl)
            % the effective EI column follows whatever is typed
            tbl.Data = alesingCoveInternal.eiPreview(tbl.Data, tbl.UserData.sym);
        end

        function EIDlgFill(app, tbl)
            % the first row copied down both columns
            D = tbl.Data;
            for col = 2:3
                for k = 2:size(D, 1)
                    D{k, col} = D{1, col};
                end
            end
            tbl.Data = alesingCoveInternal.eiPreview(D, tbl.UserData.sym);
        end

        function EIDlgApply(app, tbl, err, dlg)
            % the table back into the two fields, only when every row parses
            D = tbl.Data;
            e = cell(1, size(D, 1));  iv = e;
            for k = 1:numel(e)
                try
                    uu = alesingCoveInternal.units();
                    alesingCoveInternal.parseFlexField(D{k, 2}, [D{k, 1} ' E'], uu.Ebase, tbl.UserData.sym);
                    alesingCoveInternal.parseFlexField(D{k, 3}, [D{k, 1} ' I'], uu.Ibase, tbl.UserData.sym);
                catch err2
                    err.Text = err2.message;
                    return
                end
                e{k} = strtrim(D{k, 2});
                iv{k} = strtrim(D{k, 3});
            end
            app.EField.Value = strjoin(e, ' ');
            app.IField.Value = strjoin(iv, ' ');
            delete(dlg);
            figure(app.UIFigure);
            updateSpans(app);
            setStatus(app, sprintf('E and I set for %d row(s). Press CALCULATE.', numel(e)), 'info');
        end


        function styleApp(app)
            c = alesingCoveInternal.beamColors();
            app.UIFigure.Theme = 'light'; app.UIFigure.Color = c.bg;
            names = properties(app);
            for k = 1:numel(names)
                obj = app.(names{k});
                if isempty(obj) || ~isscalar(obj) || ~isgraphics(obj), continue; end
                if isprop(obj,'FontColor'), obj.FontColor = c.text; end
                if isprop(obj,'FontName'), obj.FontName = alesingCoveInternal.pickFont(); end
                if isa(obj,'matlab.ui.control.Table')
                    obj.BackgroundColor = [c.surface; c.zebra]; obj.ForegroundColor = c.text;
                elseif isa(obj,'matlab.ui.control.Button')
                    obj.BackgroundColor = c.btn;
                elseif isa(obj,'matlab.ui.container.GridLayout')
                    obj.BackgroundColor = c.bg;
                elseif isprop(obj,'BackgroundColor') && ~isa(obj,'matlab.ui.Figure')
                    obj.BackgroundColor = c.surface;
                end
            end
            app.CalcButton.BackgroundColor = c.violet; app.CalcButton.FontColor = [1 1 1]; app.CalcButton.FontWeight = 'bold';
            app.ClearButton.BackgroundColor = c.navy; app.ClearButton.FontColor = [1 1 1];
        end
        function plotResults(app, B, R)
            c = alesingCoveInternal.beamColors();  L = B.L;

            try
                alesingCoveInternal.wipeAxes(app.axBeam);  alesingCoveInternal.drawBeam(app.axBeam, B, R);
                title(app.axBeam, sprintf('%s   |   Degree of indeterminacy = %d', B.name, R.DI), ...
                      'FontSize',10, 'Interpreter','none', 'Color',c.text);
            catch err
                disp(getReport(err));
                setStatus(app, ['Beam drawing failed: ' err.message], 'warn');
            end

            alesingCoveInternal.wipeAxes(app.axV);
            alesingCoveInternal.diagram(app.axV, R.xp, R.Vp, c.shear, c.beam, L);
            title(app.axV, 'Shear Force Diagram (V)', 'FontSize',10, 'FontWeight','bold', 'Color',c.text);
            ylabel(app.axV, 'Shear (kN)');

            alesingCoveInternal.wipeAxes(app.axM);
            alesingCoveInternal.diagram(app.axM, R.xp, R.Mp, c.moment, c.beam, L);
            title(app.axM, 'Bending Moment Diagram (M)', 'FontSize',10, 'FontWeight','bold', 'Color',c.text);
            ylabel(app.axM, 'Moment (kN·m)');

            alesingCoveInternal.wipeAxes(app.axD);
            if B.sym
                yd = R.vp;  lab = 'EI v (kN-m^3)';
            else
                yd = R.vp*alesingCoveInternal.engSc();  lab = 'Deflection (mm)';
            end
            alesingCoveInternal.diagram(app.axD, R.xp, yd, c.deflec, c.beam, L);
            title(app.axD, 'Beam Deflection Curve (δ)', 'FontSize',10, 'FontWeight','bold', 'Color',c.text);
            xlabel(app.axD, 'Beam Position x (m)');  ylabel(app.axD, lab);
            axs = {app.axBeam, app.axV, app.axM, app.axD};   % every axis is put
            for kk = 1:numel(axs), alesingCoveInternal.engAx(axs{kk}); end       % into the live system
        end

        function clearCharts(app)
            c = alesingCoveInternal.beamColors();
            axesList = {app.axBeam,app.axV,app.axM,app.axD};
            titles = {'Beam and free-body diagram','Shear-force diagram','Bending-moment diagram','Deflection diagram'};
            for k = 1:4
                alesingCoveInternal.wipeAxes(axesList{k});
                axesList{k}.Color = c.surface;
                axesList{k}.XColor = c.muted; axesList{k}.YColor = c.muted;
                axesList{k}.Toolbar.Visible = 'off';
                title(axesList{k},titles{k},'Color',c.text,'FontSize',11);
            end
        end

        function maximizeBlueWorkspace(app)
            c = alesingCoveInternal.beamColors();
            fn = alesingCoveInternal.pickFont();
            app.RootGrid.Padding = [8 8 8 8];
            app.RootGrid.RowHeight = {44,'1x',48};
            app.RootGrid.RowSpacing = 6;
            app.WorkspaceGrid.Padding = [0 0 0 0];
            app.WorkspaceGrid.ColumnWidth = {350,'1x'};
            app.WorkspaceGrid.ColumnSpacing = 8;
            app.SettingsGrid.Padding = [8 8 8 8];
            app.SettingsGrid.RowSpacing = 7;
            app.SettingsGrid.ColumnWidth = {105,'1x'};
            app.MethodLabel.Visible = 'off';
            app.MethodDropDown.Layout.Row = 2;
            app.MethodDropDown.Layout.Column = [1 2];
            app.SettingsGrid.RowHeight = {34,42,34,34,34,34,34,40,65,26};
            app.FooterGrid.Padding = [0 0 0 0];
            app.FooterGrid.ColumnWidth = {'1x',170,100,100};
            app.FinalGrid.Padding = [8 8 8 8];
            app.FinalGrid.RowHeight = {32,'1x','1x'};
            app.FBDGrid.Padding = [4 4 4 4];
            app.ChartGrid.Padding = [4 4 4 4];
            app.ChartGrid.RowSpacing = 4;
            app.SupportGrid.Padding = [6 6 6 6];
            app.LoadGrid.Padding = [6 6 6 6];
            app.SupTable.ColumnWidth = {85,'1x',130,160};
            app.LoadTable.ColumnWidth = {140,'1x','1x'};
            app.ReactTable.ColumnWidth = {110,'1x','1x'};
            names = properties(app);
            for k = 1:numel(names)
                obj = app.(names{k});
                if isempty(obj) || ~isscalar(obj) || ~isgraphics(obj), continue; end
                if isprop(obj,'FontName'), obj.FontName = fn; end
                if isprop(obj,'FontSize'), obj.FontSize = 13; end
                if isa(obj,'matlab.ui.control.Label') || isa(obj,'matlab.ui.control.Button')
                    obj.FontWeight = 'bold';
                end
            end
            app.Heading.FontSize = 25; app.Heading.FontColor = c.navy;
            app.FinalLabel.FontSize = 18; app.FinalLabel.FontColor = c.primary;
            app.CreatorNote.FontSize = 11; app.CreatorNote.FontColor = c.muted;
            app.UnitsNote.FontSize = 11; app.UnitsNote.WordWrap = 'on';
            app.SummaryArea.FontName = 'Consolas';
            try
                app.UIFigure.WindowState = 'maximized';
            catch
                screen = get(groot,'ScreenSize');
                app.UIFigure.Position = [screen(1)+8 screen(2)+40 screen(3)-16 screen(4)-80];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            alesingCoveInternal.units(false);
            app.UnitsDD.Value = 'Metric  (kN, m)';
            app.SupTable.Data = {0,'Pinned',[],[];5,'Roller',[],[];10,'Roller',[],[]};
            app.Loads = struct('Type','UDL','A',0,'B',10,'V1',10,'V2',10,'Dir','Downward');
            app.ReactTable.Data = cell(0,3);
            app.SupTable.ColumnFormat = {'numeric',{'Fixed','Pinned','Roller','Spring'},'numeric','numeric'};
            applyUnits(app); refreshSupLabels(app); refreshLoadTable(app);
            syncFmOptions(app); updateSpans(app); setBusy(app,false);
            styleApp(app); clearCharts(app);
            maximizeBlueWorkspace(app);
            setStatus(app,'Ready. Set up supports and loads, then run the analysis.','info');
        end

        % Value changed function: UnitsDD
        function UnitsChanged(app, event)
            eng = contains(app.UnitsDD.Value, 'English');
            alesingCoveInternal.units(eng);
            applyUnits(app); updateSpans(app);
            app.ReactTable.Data = cell(0,3);
            app.SummaryArea.Value = {'Units changed. Recalculate to update results and diagrams.'};
            clearCharts(app);
            setStatus(app,'Units changed. Existing numbers are not automatically converted.','info');
        end

        % Value changed function: FmDeflDrop, PerSpanCheck, StepField, 
        % ...and 1 other component
        function PropsChanged(app, event)
            syncFmOptions(app);
            updateSpans(app);
        end

        % Button pushed function: EIButton
        function EIButtonPushed(app, event)
            showEIEditor(app);
        end

        % Callback function: LengthField, SupTable
        function SupEdited(app, event)
            sortSupports(app);
            updateSpans(app);
        end

        % Cell selection callback: SupTable
        function SupSelected(app, event)
            if isempty(event.Indices), app.selSup = [];
            else, app.selSup = unique(event.Indices(:,1))';
            end
        end

        % Button pushed function: DelSupButton
        function DelSupPushed(app, event)
            if isempty(app.selSup)
                setStatus(app, 'Click a support row first, then press Delete selected.', 'warn');
                return;
            end
            app.SupTable.Data(app.selSup,:) = [];
            app.selSup = [];
            refreshSupLabels(app);
            updateSpans(app);
        end

        % Cell selection callback: LoadTable
        function LoadSelected(app, event)
            if isempty(event.Indices), app.selLoad = [];
            else, app.selLoad = unique(event.Indices(:,1))';
            end
        end

        % Double-clicked callback: LoadTable
        function LoadDoubleClicked(app, event)
            r = event.InteractionInformation.Row;
            if ~isempty(r) && r >= 1 && r <= numel(app.Loads)
                showLoadDialog(app, r);
            end
        end

        % Button pushed function: AddLoadButton
        function AddLoadPushed(app, event)
            showLoadDialog(app, []);
        end

        % Button pushed function: EditLoadButton
        function EditLoadPushed(app, event)
            if isempty(app.selLoad)
                setStatus(app, 'Click a load row first, then press Edit selected.', 'warn');
                return;
            end
            showLoadDialog(app, app.selLoad(1));
        end

        % Button pushed function: DelLoadButton
        function DelLoadPushed(app, event)
            if isempty(app.selLoad)
                setStatus(app, 'Click a load row first, then press Delete selected.', 'warn');
                return;
            end
            app.Loads(app.selLoad) = [];
            app.selLoad = [];
            refreshLoadTable(app);
        end

        % Callback function
        function LoadDlgTypeChanged(app, event)
            dlg = ancestor(event.Source, 'figure');
            buildLoadFields(app, dlg);
            U = dlg.UserData;
            U.err.Text = '';
        end

        % Callback function
        function LoadDlgCancel(app, event)
            delete(ancestor(event.Source, 'figure'));
            figure(app.UIFigure);
        end

        % Callback function
        function LoadDlgOK(app, event)
            dlg = ancestor(event.Source, 'figure');
            U = dlg.UserData;  f = U.f;  ty = U.dd.Value;  L = app.LengthField.Value;
            try
                ld = struct('Type',ty, 'A',[], 'B',[], 'V1',[], 'V2',[], 'Dir',f.Dir.Value);
                switch ty
                    case {'Point','Moment','Horizontal'}
                        ld.A  = alesingCoveInternal.readPositive(f.A,  'Position x', false);
                        ld.V1 = alesingCoveInternal.readPositive(f.V1, 'Magnitude',  true);
                        if ld.A > L, error('Position must be between 0 and %g m.', L); end
                    case 'UDL'
                        ld.A  = alesingCoveInternal.readPositive(f.A,  'Start x',   false);
                        ld.B  = alesingCoveInternal.readPositive(f.B,  'End x',     false);
                        ld.V1 = alesingCoveInternal.readPositive(f.V1, 'Intensity w', true);
                        ld.V2 = ld.V1;
                        if ld.B <= ld.A, error('End x must be greater than start x.'); end
                        if ld.B > L, error('The load must stay inside the beam (0 to %g m).', L); end
                    case 'UVL'
                        ld.A  = alesingCoveInternal.readPositive(f.A,  'Start x', false);
                        ld.B  = alesingCoveInternal.readPositive(f.B,  'End x',   false);
                        ld.V1 = alesingCoveInternal.readPositive(f.V1, 'Intensity w₁', false);
                        ld.V2 = alesingCoveInternal.readPositive(f.V2, 'Intensity w₂', false);
                        if ld.B <= ld.A, error('End x must be greater than start x.'); end
                        if ld.B > L, error('The load must stay inside the beam (0 to %g m).', L); end
                        if ld.V1 == 0 && ld.V2 == 0, error('w₁ and w₂ cannot both be zero.'); end
                end
            catch err
                U.err.Text = err.message;
                return;
            end
            if isempty(U.row), app.Loads(end+1) = ld; else, app.Loads(U.row) = ld; end
            app.selLoad = [];
            refreshLoadTable(app);
            delete(dlg);
            figure(app.UIFigure);                 % go back to the beam analysis window
            setStatus(app, 'Load saved. Add another, or press CALCULATE.', 'info');
        end

        % Button pushed function: IButton
        function IButtonPushed(app, event)
            sel = uiconfirm(app.UIFigure, ...
                'Does the moment of inertia I vary from span to span?', 'Moment of inertia I', ...
                'Options', {'Yes - varying I','No - uniform I','Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 3, 'Icon', 'question');
            switch sel
                case 'Yes - varying I', showIDialog(app, true);
                case 'No - uniform I',  showIDialog(app, false);
            end
        end

        % Callback function
        function IDlgCancel(app, event)
            delete(ancestor(event.Source, 'figure'));
            figure(app.UIFigure);
        end

        % Callback function
        function IDlgOK(app, event)
            dlg = ancestor(event.Source, 'figure');
            U = dlg.UserData;  D = U.tbl.Data;
            v = zeros(1, size(D,1));
            for k = 1:numel(v)
                v(k) = alesingCoveInternal.cellNum(D{k,2});
                if isnan(v(k)) || v(k) <= 0
                    U.err.Text = sprintf('%s: enter a positive number for I.', D{k,1});
                    return;
                end
            end
            if U.varying
                p = arrayfun(@(q) sprintf('%.10g', q), v, 'UniformOutput', false);
                app.IField.Value = strjoin(p, ' ');
                msg = sprintf(alesingCoveInternal.engLbl('Varying I set: %s mm⁴ (one per span). Press CALCULATE.'), app.IField.Value);
            else
                app.IField.Value = sprintf('%.10g', v(1));
                msg = sprintf(alesingCoveInternal.engLbl('Uniform I = %s mm⁴ for all spans. Press CALCULATE.'), app.IField.Value);
            end
            delete(dlg);
            figure(app.UIFigure);
            setStatus(app, msg, 'info');
        end

        % Value changed function: MethodDropDown
        function MethodChanged(app, event)
            syncFmOptions(app);
            restricted = any(strcmp(app.MethodDropDown.Value,{'Three-Moment Equation','Moment Distribution Method'}));
            if restricted, app.HingeField.Enable = 'off'; else, app.HingeField.Enable = 'on'; end
            setStatus(app,'Method changed. Calculate again to update results.','info');
        end

        % Button pushed function: AddSupButton
        function AddSupPushed(app, event)
            data = app.SupTable.Data;
            if istable(data), data=table2cell(data); elseif isempty(data), data=cell(0,4); elseif isnumeric(data), data=num2cell(data); end
            data(end+1,:) = {[], 'Roller', [], []}; app.SupTable.Data = data;
            refreshSupLabels(app); updateSpans(app);
            setStatus(app,'Support added. Enter its position to continue.','info');
            drawnow; scroll(app.SupTable,'row',size(data,1));
        end

        % Button pushed function: CalcButton
        function CalcPushed(app, event)
            if app.Busy, return; end
            app.StopRequested = false; setBusy(app,true);
            cleanup = onCleanup(@() setBusy(app,false));
            try
                B = readBeam(app); method = app.MethodDropDown.Value;
                switch method
                    case 'Slope-Deflection Method', fcn = @alesingCoveInternal.sdAnalyze;
                    case 'Three-Moment Equation'
                        if any(B.sup(:,2)==4), error('Three-moment does not support springs. Use slope-deflection or force method.'); end
                        if ~isempty(B.hinge), error('Three-moment does not support internal hinges.'); end
                        fcn = @alesingCoveInternal.tmAnalyze;
                    case 'Force Method', fcn = @alesingCoveInternal.analyzeForceMethod;
                    case 'Moment Distribution Method', fcn = @alesingCoveInternal.analyzeMomentDistribution;
                end
                setStatus(app,'Calculating…','info'); drawnow;
                captured = evalc('R = fcn(B);');
                if app.StopRequested, setStatus(app,'Cancelled; prior results retained.','warn'); return; end
                showResults(app,B,R,method);
                plotResults(app,B,R);
                app.InputTabs.SelectedTab = app.AnswersTab;
                setStatus(app,'Analysis complete. Results, FBD and diagrams are ready.','ok');
            catch ME
                setStatus(app,ME.message,'err');
                uialert(app.UIFigure,ME.message,'Cannot calculate');
            end
        end

        % Button pushed function: StopButton
        function StopPushed(app, event)
            app.StopRequested = true;
            setStatus(app,'Cancellation requested. Waiting for solver to return.','warn');
        end

        % Button pushed function: ClearButton
        function ClearPushed(app, event)
            if app.Busy, return; end
            answer = uiconfirm(app.UIFigure,'Clear inputs and results?','Reset', ...
                'Options',{'Reset','Cancel'},'DefaultOption',2,'CancelOption',2);
            if ~strcmp(answer,'Reset'), return; end
            u=alesingCoveInternal.units(); app.TitleField.Value='Beam'; app.LengthField.Value=u.Ldef;
            app.EField.Value=u.Etxt; app.IField.Value=u.Itxt;
            app.HingeField.Value=''; app.StepField.Value=''; app.SymEICheck.Value=false; app.PerSpanCheck.Value=false;
            app.SupTable.Data=cell(0,4); app.selSup=[]; app.selLoad=[];
            app.Loads=struct('Type',{},'A',{},'B',{},'V1',{},'V2',{},'Dir',{});
            app.ReactTable.Data=cell(0,3); app.SummaryArea.Value={'Inputs and results cleared.'};
            clearCharts(app);
            refreshSupLabels(app); refreshLoadTable(app); updateSpans(app); syncFmOptions(app);
            setStatus(app,'Cleared.','info');
        end

        % Close request function: UIFigure
        function MainClose(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'AlesingMatlab | Blue Structural Lab';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainClose, true);
            app.RootGrid = uigridlayout(app.UIFigure);
            app.FooterGrid = uigridlayout(app.RootGrid);
            app.ClearButton = uibutton(app.FooterGrid, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPushed, true);
            app.ClearButton.Text = 'RESET';
            app.StopButton = uibutton(app.FooterGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'STOP';
            app.CalcButton = uibutton(app.FooterGrid, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcPushed, true);
            app.CalcButton.Text = 'RUN ANALYSIS';
            app.StatusLabel = uilabel(app.FooterGrid);
            app.StatusLabel.Text = 'Ready. Set up supports and loads, then run the analysis.';
            app.WorkspaceGrid = uigridlayout(app.RootGrid);
            app.SettingsGrid = uigridlayout(app.WorkspaceGrid);
            app.CreatorNote = uilabel(app.SettingsGrid);
            app.CreatorNote.Text = 'Built for clear results · NEBRIJA';
            app.UnitsNote = uilabel(app.SettingsGrid);
            app.UnitsNote.Text = 'kN and m  (E in kN/m2, I in mm4, deflections in mm)';
            app.EIButton = uibutton(app.SettingsGrid, 'push');
            app.EIButton.ButtonPushedFcn = createCallbackFcn(app, @EIButtonPushed, true);
            app.EIButton.Text = 'Configure stiffness · E & I';
            app.IField = uieditfield(app.SettingsGrid, 'text');
            app.IField.Tooltip = 'One value per span (500e6 1e9 ...), or a multiple of I such as 2I, I/2, 3I/4 (or use the Set I button)';
            app.IField.Value = '500e6';
            app.ILab = uilabel(app.SettingsGrid);
            app.ILab.Text = 'I (mm⁴)';
            app.EField = uieditfield(app.SettingsGrid, 'text');
            app.EField.Tooltip = 'One value for all spans, or one per span (200e6 200e6), or a multiple of E such as 2E or E/2';
            app.EField.Value = '200e6';
            app.ELab = uilabel(app.SettingsGrid);
            app.ELab.Text = 'E (kN/m²)';
            app.HingeField = uieditfield(app.SettingsGrid, 'text');
            app.HingeLab = uilabel(app.SettingsGrid);
            app.HingeLab.Text = 'Hinges x (m)';
            app.LengthField = uieditfield(app.SettingsGrid, 'numeric');
            app.LengthField.Limits = [1e-06 Inf];
            app.LengthField.ValueChangedFcn = createCallbackFcn(app, @SupEdited, true);
            app.LengthField.Value = 10;
            app.LenLab = uilabel(app.SettingsGrid);
            app.LenLab.Text = 'Length L (m)';
            app.TitleField = uieditfield(app.SettingsGrid, 'text');
            app.TitleField.Value = 'Beam';
            app.TitleLabel = uilabel(app.SettingsGrid);
            app.TitleLabel.Text = 'Project';
            app.MethodDropDown = uidropdown(app.SettingsGrid);
            app.MethodDropDown.Items = {'Slope-Deflection Method', 'Three-Moment Equation', 'Force Method', 'Moment Distribution Method'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodChanged, true);
            app.MethodDropDown.Value = 'Slope-Deflection Method';
            app.MethodLabel = uilabel(app.SettingsGrid);
            app.MethodLabel.Visible = 'off';
            app.MethodLabel.Text = 'Solve with';
            app.UnitsDD = uidropdown(app.SettingsGrid);
            app.UnitsDD.Items = {'Metric  (kN, m)', 'English  (kip, ft)'};
            app.UnitsDD.ValueChangedFcn = createCallbackFcn(app, @UnitsChanged, true);
            app.UnitsDD.Value = 'Metric  (kN, m)';
            app.UnitsLabel = uilabel(app.SettingsGrid);
            app.UnitsLabel.Text = 'Units';
            app.InputTabs = uitabgroup(app.WorkspaceGrid);
            app.SupportTab = uitab(app.InputTabs);
            app.SupportTab.Title = 'Supports';
            app.SupportGrid = uigridlayout(app.SupportTab);
            app.SupportButtons = uigridlayout(app.SupportGrid);
            app.DelSupButton = uibutton(app.SupportButtons, 'push');
            app.DelSupButton.ButtonPushedFcn = createCallbackFcn(app, @DelSupPushed, true);
            app.DelSupButton.Text = 'Remove support';
            app.AddSupButton = uibutton(app.SupportButtons, 'push');
            app.AddSupButton.ButtonPushedFcn = createCallbackFcn(app, @AddSupPushed, true);
            app.AddSupButton.Text = '+ Add support';
            app.SupTable = uitable(app.SupportGrid);
            app.SupTable.ColumnName = {'x (m)'; 'Type'; 'k (kN/m)'; 'Settlement (mm)'};
            app.SupTable.RowName = {'A'; 'B'; 'C'};
            app.SupTable.ColumnEditable = [true true true true];
            app.SupTable.CellEditCallback = createCallbackFcn(app, @SupEdited, true);
            app.SupTable.CellSelectionCallback = createCallbackFcn(app, @SupSelected, true);
            app.LoadTab = uitab(app.InputTabs);
            app.LoadTab.Title = 'Loads';
            app.LoadGrid = uigridlayout(app.LoadTab);
            app.LoadButtons = uigridlayout(app.LoadGrid);
            app.DelLoadButton = uibutton(app.LoadButtons, 'push');
            app.DelLoadButton.ButtonPushedFcn = createCallbackFcn(app, @DelLoadPushed, true);
            app.DelLoadButton.Text = 'Remove';
            app.EditLoadButton = uibutton(app.LoadButtons, 'push');
            app.EditLoadButton.ButtonPushedFcn = createCallbackFcn(app, @EditLoadPushed, true);
            app.EditLoadButton.Text = 'Edit';
            app.AddLoadButton = uibutton(app.LoadButtons, 'push');
            app.AddLoadButton.ButtonPushedFcn = createCallbackFcn(app, @AddLoadPushed, true);
            app.AddLoadButton.Text = '+ Add load';
            app.LoadTable = uitable(app.LoadGrid);
            app.LoadTable.ColumnName = {'Type'; 'Position'; 'Value'};
            app.LoadTable.ColumnEditable = [false false false];
            app.LoadTable.CellSelectionCallback = createCallbackFcn(app, @LoadSelected, true);
            app.LoadTable.DoubleClickedFcn = createCallbackFcn(app, @LoadDoubleClicked, true);
            app.OptionsTab = uitab(app.InputTabs);
            app.OptionsTab.Title = 'Advanced';
            app.OptionsGrid = uigridlayout(app.OptionsTab);
            app.SpanLabel = uilabel(app.OptionsGrid);
            app.SpanLabel.Text = 'Spans: 2  (0-5, 5-10 m)';
            app.IButton = uibutton(app.OptionsGrid, 'push');
            app.IButton.ButtonPushedFcn = createCallbackFcn(app, @IButtonPushed, true);
            app.IButton.Text = 'Set uniform / varying I';
            app.TargetGrid = uigridlayout(app.OptionsGrid);
            app.FmTargetField = uieditfield(app.TargetGrid, 'numeric');
            app.FmTargetField.Limits = [0 Inf];
            app.FmTargetField.Enable = 'off';
            app.TgtLab = uilabel(app.TargetGrid);
            app.TgtLab.Text = 'Check v at x (m)';
            app.FmDeflDrop = uidropdown(app.OptionsGrid);
            app.FmDeflDrop.Items = {'Double Integration', 'Moment Area', 'Conjugate Beam', 'Virtual Work'};
            app.FmDeflDrop.ValueChangedFcn = createCallbackFcn(app, @PropsChanged, true);
            app.FmDeflDrop.Enable = 'off';
            app.FmDeflDrop.Value = 'Double Integration';
            app.StepGrid = uigridlayout(app.OptionsGrid);
            app.StepField = uieditfield(app.StepGrid, 'text');
            app.StepField.ValueChangedFcn = createCallbackFcn(app, @PropsChanged, true);
            app.StepField.Enable = 'off';
            app.StepLab = uilabel(app.StepGrid);
            app.StepLab.Text = 'Steps x (m)';
            app.PerSpanCheck = uicheckbox(app.OptionsGrid);
            app.PerSpanCheck.ValueChangedFcn = createCallbackFcn(app, @PropsChanged, true);
            app.PerSpanCheck.Text = 'Specify EI per span / section';
            app.SymEICheck = uicheckbox(app.OptionsGrid);
            app.SymEICheck.ValueChangedFcn = createCallbackFcn(app, @PropsChanged, true);
            app.SymEICheck.Text = 'Symbolic / relative E and I';
            app.AnswersTab = uitab(app.InputTabs);
            app.AnswersTab.Title = 'Results';
            app.FinalGrid = uigridlayout(app.AnswersTab);
            app.SummaryArea = uitextarea(app.FinalGrid);
            app.SummaryArea.Editable = 'off';
            app.SummaryArea.Value = {'Set up supports and loads, then run the analysis.'};
            app.ReactTable = uitable(app.FinalGrid);
            app.ReactTable.ColumnName = {'Reaction'; 'Value'; 'Support'};
            app.ReactTable.ColumnEditable = [false false false];
            app.FinalLabel = uilabel(app.FinalGrid);
            app.FinalLabel.Text = 'ANALYSIS RESULTS';
            app.FBDTab = uitab(app.InputTabs);
            app.FBDTab.Title = 'Beam & FBD';
            app.FBDGrid = uigridlayout(app.FBDTab);
            app.axBeam = uiaxes(app.FBDGrid);
            title(app.axBeam, 'Beam and free-body diagram')
            app.ChartsTab = uitab(app.InputTabs);
            app.ChartsTab.Title = 'Diagrams';
            app.ChartGrid = uigridlayout(app.ChartsTab);
            app.axD = uiaxes(app.ChartGrid);
            title(app.axD, 'Deflection diagram')
            app.axM = uiaxes(app.ChartGrid);
            title(app.axM, 'Bending-moment diagram')
            app.axV = uiaxes(app.ChartGrid);
            title(app.axV, 'Shear-force diagram')
            app.Heading = uilabel(app.RootGrid);
            app.Heading.Text = ' STRUCTURAL ANALYSIS BY: NEBRIJA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NEBRIJA_STRUCTURAL_ANALYSIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end